classdef E3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exercise3UIFigure       matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        CurrentXEditFieldLabel  matlab.ui.control.Label
        CurrentXEditField       matlab.ui.control.NumericEditField
        CurrentYEditFieldLabel  matlab.ui.control.Label
        CurrentYEditField       matlab.ui.control.NumericEditField
        StartButton             matlab.ui.control.Button
        NameMukeshAryalLabel    matlab.ui.control.Label
        ID268456Label           matlab.ui.control.Label
    end


    properties (Access = private)
        Running = 0; % Description, not running at first.
    end

    methods (Access = private)
    
        function StartAnimation(app)
            x = [0 0];
            h = plot(app.UIAxes,x(1), x(2), 'o', 'MarkerSize', 5,...
	       'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
        
            ha = app.UIAxes; % Handle to current axes       
            
            
            app.CurrentXEditField.Value = x(1);
            app.CurrentYEditField.Value = x(2);
           
            xx = 1; yy = 1;       % The amount to increase/decrease the axes limits
            axis(ha,[-xx xx -yy yy]) % Set initial axes limits
            
            step = 0.1;
            while app.Running == 1
                theta = rand*2*pi; % Generate a random angle
                x = x + step*[cos(theta) sin(theta)]; % Update coordinates
                h.XData = x(1); h.YData = x(2); % Update plot
                
                % Print the coordinates of the point
                app.CurrentXEditField.Value = x(1);
                app.CurrentYEditField.Value = x(2);
                
                % Check axes limits. We can update XLim and YLim in place
                if x(1) < ha.XLim(1)
                    ha.XLim(1) = ha.XLim(1) - xx;
                elseif x(1) > ha.XLim(2)
                    ha.XLim(2) = ha.XLim(2) + xx;
                end
                
                if x(2) < ha.YLim(1)
                    ha.YLim(1) = ha.YLim(1) - yy;
                elseif x(2) > ha.YLim(2)
                    ha.YLim(2) = ha.YLim(2) + yy;
                end
                
                drawnow % Draw the new plot
            end
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           if app.Running == 0
               app.StartButton.Text = 'Stop'; 
               app.Running = 1;
               StartAnimation(app);               
           else
               app.StartButton.Text = 'Start';
               app.Running = 0;               
           end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exercise3UIFigure = uifigure;
            app.Exercise3UIFigure.Name = 'Exercise 3';
            app.UIAxes = uiaxes(app.Exercise3UIFigure);
            title(app.UIAxes, 'Random Walk')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CurrentXEditFieldLabel = uilabel(app.Exercise3UIFigure);
            app.CurrentXEditFieldLabel.Text = 'Current X';
            app.CurrentXEditField = uieditfield(app.Exercise3UIFigure, 'numeric');
            app.CurrentXEditField.Editable = 'off';
            app.CurrentYEditFieldLabel = uilabel(app.Exercise3UIFigure);
            app.CurrentYEditFieldLabel.Text = 'Current Y';
            app.CurrentYEditField = uieditfield(app.Exercise3UIFigure, 'numeric');
            app.CurrentYEditField.Editable = 'off';
            app.StartButton = uibutton(app.Exercise3UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NameMukeshAryalLabel = uilabel(app.Exercise3UIFigure);
            app.NameMukeshAryalLabel.Text = 'Name: Mukesh Aryal';
            app.ID268456Label = uilabel(app.Exercise3UIFigure);
            app.ID268456Label.Text = 'ID: 268456';
        end
    end

    methods (Access = public)

        % Construct app
        function app = E3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exercise3UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exercise3UIFigure)
        end
    end
end